classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RUNSwitchLabel       matlab.ui.control.Label
        RUNSwitch            matlab.ui.control.Switch
        SpeedEditFieldLabel  matlab.ui.control.Label
        SpeedEditField       matlab.ui.control.NumericEditField
        Knob2                matlab.ui.control.Knob
        Knob_2               matlab.ui.control.DiscreteKnob
        UIAxes               matlab.ui.control.UIAxes
        SpeedGaugeLabel      matlab.ui.control.Label
        SpeedGauge           matlab.ui.control.Gauge
        ApplyButton          matlab.ui.control.StateButton
        TEMPGaugeLabel       matlab.ui.control.Label
        TEMPGauge            matlab.ui.control.LinearGauge
        TorqueGaugeLabel     matlab.ui.control.Label
        TorqueGauge          matlab.ui.control.Gauge
        Button               matlab.ui.control.Button
        DirectionGaugeLabel  matlab.ui.control.Label
        DirectionGauge       matlab.ui.control.SemicircularGauge
        OFFLampLabel         matlab.ui.control.Label
        OFFLamp              matlab.ui.control.Lamp
        Button_2             matlab.ui.control.Button
        StartUpLabel         matlab.ui.control.Label
        ControlLabel         matlab.ui.control.Label
        MonteringLabel       matlab.ui.control.Label
        ONLampLabel          matlab.ui.control.Label
        ONLamp               matlab.ui.control.Lamp
        DirectionLampLabel   matlab.ui.control.Label
        DirectionLamp        matlab.ui.control.Lamp
    end


    properties (Access = private)
        Status % Description
        Direction % Description
        Speed % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.RUNSwitch.Value == "On"
                app.Status = 1;
                app.OFFLamp.Color = [0 0 0];
                app.ONLamp.Color = [0 1 0];
            else
                app.Status = 0;
                app.ONLamp.Color = [0 0 0];
                app.OFFLamp.Color = [1 0 0];
            end
            set_param('Simulation/Program/Status', 'Value', num2str(app.Status));
            
            
            app.Speed = app.Knob2.Value;
            set_param('Simulation/Program/RPM', 'Value', num2str(app.Speed));
            
            
           if app.Knob_2.Value == "CW"
                app.Direction = 1 ; 
                app.DirectionLamp.Color = [0 1 1];
           else
                app.Direction = 0 ; 
                app.DirectionLamp.Color = [1 1 0];
           end
            set_param('Simulation/Program/Rotation', 'Value', num2str(app.Direction));
            
        end

        % Value changed function: RUNSwitch
        function RUNSwitchValueChanged(app, event)
    
            if app.RUNSwitch.Value == "On"
                app.Status = 1;
                app.OFFLamp.Color = [0 0 0];
                app.ONLamp.Color = [0 1 0];
            else
                app.Status = 0;
                app.ONLamp.Color = [0 0 0];
                app.OFFLamp.Color = [1 0 0];
            end
            set_param('Simulation/Program/Status', 'Value', num2str(app.Status));
  
        end

        % Value changed function: Knob2
        function Knob2ValueChanged(app, event)
            app.Speed = app.Knob2.Value;
            app.SpeedEditField.Value = app.Knob2.Value;
            set_param('Simulation/Program/RPM', 'Value', num2str(app.Speed));
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
         if  app.Status == 1
                app.Knob_2.Value= event.PreviousValue;
                uialert(app.UIFigure, 'you must shut down the motor first' , "Warnings");
                
           else
                if app.Knob_2.Value == "CW"
                    app.Direction = 1 ;
                    app.DirectionLamp.Color = [0 1 1];
               else
                    app.Direction = 0 ; 
                    app.DirectionLamp.Color = [1 1 0];
               end
                set_param('Simulation/Program/Rotation', 'Value', num2str(app.Direction));
          end
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            app.Speed = app.SpeedEditField.Value;
            app.Knob2.Value = app.SpeedEditField.Value;
            set_param('Simulation/Program/RPM', 'Value', num2str(app.Speed));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RUNSwitchLabel = uilabel(app.UIFigure);
            app.RUNSwitchLabel.Text = 'RUN';
            app.RUNSwitch = uiswitch(app.UIFigure, 'slider');
            app.RUNSwitch.ValueChangedFcn = createCallbackFcn(app, @RUNSwitchValueChanged, true);
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.Limits = [0 1400];
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.Knob2 = uiknob(app.UIFigure, 'continuous');
            app.Knob2.Limits = [0 1400];
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @Knob2ValueChanged, true);
            app.Knob_2 = uiknob(app.UIFigure, 'discrete');
            app.Knob_2.Items = {'CCW', 'CW'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Value = 'CCW';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Speed')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Y')
            app.SpeedGaugeLabel = uilabel(app.UIFigure);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedGauge.Limits = [0 1400];
            app.ApplyButton = uibutton(app.UIFigure, 'state');
            app.ApplyButton.Text = 'Apply';
            app.TEMPGaugeLabel = uilabel(app.UIFigure);
            app.TEMPGaugeLabel.Text = 'TEMP';
            app.TEMPGauge = uigauge(app.UIFigure, 'linear');
            app.TorqueGaugeLabel = uilabel(app.UIFigure);
            app.TorqueGaugeLabel.Text = 'Torque';
            app.TorqueGauge = uigauge(app.UIFigure, 'circular');
            app.TorqueGauge.Limits = [0 1400];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.DirectionGaugeLabel = uilabel(app.UIFigure);
            app.DirectionGaugeLabel.Text = 'Direction';
            app.DirectionGauge = uigauge(app.UIFigure, 'semicircular');
            app.DirectionGauge.Limits = [-1 1];
            app.OFFLampLabel = uilabel(app.UIFigure);
            app.OFFLampLabel.Text = 'OFF';
            app.OFFLamp = uilamp(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.StartUpLabel = uilabel(app.UIFigure);
            app.StartUpLabel.Text = 'StartUp';
            app.ControlLabel = uilabel(app.UIFigure);
            app.ControlLabel.Text = 'Control';
            app.MonteringLabel = uilabel(app.UIFigure);
            app.MonteringLabel.Text = 'Montering';
            app.ONLampLabel = uilabel(app.UIFigure);
            app.ONLampLabel.Text = 'ON';
            app.ONLamp = uilamp(app.UIFigure);
            app.DirectionLampLabel = uilabel(app.UIFigure);
            app.DirectionLampLabel.Text = 'Direction';
            app.DirectionLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end